classdef Filter_Design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FILTERDESIGNANDVIEWERLabel  matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        Tab_inputs                  matlab.ui.container.Tab
        FiltersparametersLabel      matlab.ui.control.Label
        TypeDropDownLabel           matlab.ui.control.Label
        TypeDropDown                matlab.ui.control.DropDown
        CutoffFrequencyLabel        matlab.ui.control.Label
        CutoffFrequencyValue        matlab.ui.control.NumericEditField
        QualityFactorLabel          matlab.ui.control.Label
        QualityFactorValue          matlab.ui.control.NumericEditField
        LinearGainLabel             matlab.ui.control.Label
        LinearGainValue             matlab.ui.control.NumericEditField
        ComputeFiltersDataLabel     matlab.ui.control.Label
        CreateBodesButton           matlab.ui.control.Button
        LogMessageTextAreaLabel     matlab.ui.control.Label
        LogMessage                  matlab.ui.control.TextArea
        StatusLampLabel             matlab.ui.control.Label
        StatusLamp                  matlab.ui.control.Lamp
        ViewBodesButton             matlab.ui.control.Button
        OrderLabel                  matlab.ui.control.Label
        OrderKnob                   matlab.ui.control.DiscreteKnob
        Tab_outputs                 matlab.ui.container.Tab
        UIAxesModule                matlab.ui.control.UIAxes
        UIAxesPhase                 matlab.ui.control.UIAxes
    end


    properties (Access = public)
        % Frequency/Bode vector
        f     % Frequency vector
        H_mod % Amplitude Bode
        H_arg % Phase Bode
        % Filter's parameters
        f_t = 'low '  % Type of the filter
        n_f = 1       % Order of the filter
        f_0 = 1       % Cutoff frequency of the filter
        q_0 = 1       % Quality factor of the filter
        g   = 1       % Linear gain of the filter
        % Error handling
        error_flag
        % Plots parameters
        police = 20   % Font size of labels in plots
        width  = 2    % Width of lines in plots
        Ratio  = 100  % 1 point to display every Ratio
        LineModule    % Line to plot in the Module Bode
        PatchModule   % Surface to plot in the Module Bode
        LinePhase     % Line to plot in the Phasee Bode
        PatchPhase    % Surface to plot in the Phase Bode
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Definition of the logarithmic frequency range till 1 Giga Hertz
            power_f_min = 0; power_f_max = 9; nb_pts = 1e005;
            app.f       = logspace(power_f_min,power_f_max,nb_pts);
            % Initialization of the Bode vectors
            app.H_mod   = zeros(1,nb_pts);
            app.H_arg   = zeros(1,nb_pts);
            % Definition of the logarithmic frequency range
            power_f_min = 0; power_f_max = 9;
            nb_decades  = (power_f_max-power_f_min)+1;
            set   (app.UIAxesModule,'xtick',logspace(power_f_min,power_f_max,nb_decades));
            set   (app.UIAxesPhase ,'xtick',logspace(power_f_min,power_f_max,nb_decades));
            % Initialization of the Bodes plots
            % Bode of amplitude
            app.LineModule  = line('XData',nan(size(app.f)),'YData',nan(size(app.f)),'LineStyle','-','Color','b','LineWidth',app.width,'Parent',app.UIAxesModule);
            app.PatchModule = patch('XData',nan(1,length(app.f(1:app.Ratio:end))+2),'YData',nan(1,length(app.f(1:app.Ratio:end))+2),'LineStyle','none','FaceColor','b','FaceAlpha',.2,'Parent',app.UIAxesModule);
            xlabel(app.UIAxesModule,'f [Hz]','FontName','Times','FontSize',app.police);
            ylabel(app.UIAxesModule,  '|H| [dB]','FontName','Times','FontSize',app.police);
            set   (findobj(app.UIAxesModule,'Type','text'),'FontName','Times','FontSize',app.police);
            set   (app.UIAxesModule,'FontName','Times','FontSize',app.police);
            grid  (app.UIAxesModule,'on');
            set   (app.UIAxesModule,'XMinorGrid','off');
            % Bode of phase
            app.LinePhase = line('XData',nan(size(app.f)),'YData',nan(size(app.f)),'LineStyle','-','Color','b','LineWidth',app.width,'Parent',app.UIAxesPhase);
            app.PatchPhase = patch('XData',nan(1,length(app.f(1:app.Ratio:end))+2),'YData',nan(1,length(app.f(1:app.Ratio:end))+2),'LineStyle','none','FaceColor','b','FaceAlpha',.2,'Parent',app.UIAxesPhase);
            xlabel(app.UIAxesPhase,'f [Hz]','FontName','Times','FontSize',app.police);
            ylabel(app.UIAxesPhase,'arg(H) [°]','FontName','Times','FontSize',app.police);
            set   (findobj(app.UIAxesPhase,'Type','text'),'FontName','Times','FontSize',app.police);
            set   (app.UIAxesPhase,'FontName','Times','FontSize',app.police);
            grid  (app.UIAxesPhase,'on');
            set   (app.UIAxesPhase,'XMinorGrid','off');
        end

        % Value changed function: TypeDropDown
        function TypeValueChanged(app, event)
            % Get the type of the desired filter
            switch (app.TypeDropDown.Value)
                case 'Lowpass'
                    app.f_t = 'low ';
                case 'Highpass'
                    app.f_t = 'high';
                case 'Bandpass'
                    app.f_t = 'band';
                case 'Stopband'
                    app.f_t = 'stop';
                otherwise
                    app.f_t = 'unknown';
            end
        end

        % Value changed function: OrderKnob
        function OrderValueChanged(app, event)
            app.n_f = str2double(app.OrderKnob.Value);           
        end

        % Value changed function: CutoffFrequencyValue
        function CutoffFrequencyValueChanged(app, event)
            app.f_0 = app.CutoffFrequencyValue.Value;            
        end

        % Value changed function: QualityFactorValue
        function QualityFactorValueChanged(app, event)
            app.q_0 = app.QualityFactorValue.Value;            
        end

        % Value changed function: LinearGainValue
        function LinearGainValueChanged(app, event)
            app.g = app.LinearGainValue.Value;            
        end

        % Button pushed function: CreateBodesButton
        function CreateBodesButtonPushed(app, event)
            % Bodes calculation
            [app.H_mod,app.H_arg,app.error_flag] = filter_bode(app.f,app.f_0,app.q_0,app.g,app.f_t,app.n_f);
            % Check if an error occured during the computation of the Bodes
            if (app.error_flag ~= 0)
                error_text = 'Bodes cannot be maid due to a misconfiguration of parameters.';
                switch (app.error_flag)
                    case 1
                        error_text = [error_text 'This type of filter is not supported.'];
                    case 2
                        error_text = [error_text 'This order value is not supported for such filters. It must be an even value.'];
                    otherwise
                        error_text = [error_text 'Unknown error. Please re-submit filter''s parameters.'];
                end
                app.StatusLamp.Color = [1,0,0]; % The lamp is red
                app.LogMessage.FontColor = 'red';
                app.LogMessage.Value = error_text;
                app.ViewBodesButton.Enable = 'off';
            else
                app.StatusLamp.Color = [0,1,0]; % The lamp is green
                app.LogMessage.FontColor = 'green';
                app.LogMessage.Value = 'The Bodes computation finished successfully';
                app.ViewBodesButton.Enable = 'on';
            end
        end

        % Button pushed function: ViewBodesButton
        function ViewBodesButtonPushed(app, event)
            % Definition of the amplitude range
            min_disp_amp = -100; max_disp_amp = 100;
            min_amp = max(min(app.H_mod)); max_amp = min(max(app.H_mod));
            % Definition of the phase range
            min_disp_arg = -180; max_disp_arg = 180;
            min_arg = max(min(app.H_arg)); max_arg = min(max(app.H_arg));
            % Calculation of the optimal plot ranges
            min_H_mod = max([min_amp,min_disp_amp]);
            max_H_mod = min([max_amp,max_disp_amp]);
            min_H_arg = max([min_arg,min_disp_arg]);
            max_H_arg = min([max_arg,max_disp_arg]);
            % Update of the filer's name to display
            if (strcmp(app.f_t,'stop'))
              t_word = 'band';
            else
              t_word = 'pass';
            end
            % Bode of amplitude
            set(app.LineModule,'XData',app.f,'YData',app.H_mod);
            set(app.PatchModule,'XData',app.f([1 1:app.Ratio:end end]),'YData',[min_amp app.H_mod(1:app.Ratio:end) min_amp]);
            app.UIAxesModule.XScale = 'log';
            app.UIAxesModule.XMinorGrid = 'on';
            title(app.UIAxesModule,['Module of the ',app.f_t,'-',t_word,' filter of order ',num2str(app.n_f)],'FontName','Times','FontSize',app.police);
            axis (app.UIAxesModule,[min(app.f) 1e009, min_H_mod max_H_mod]);
            % Bode of phase
            app.UIAxesPhase.XMinorGrid = 'on';
            set(app.LinePhase,'XData',app.f,'YData',app.H_arg);
            set(app.PatchPhase,'XData',app.f([1 1:app.Ratio:end end]),'YData',[min_arg app.H_arg(1:app.Ratio:end) min_arg])
            title(app.UIAxesPhase,['Phase of the ',app.f_t,'-',t_word,' filter of order ',num2str(app.n_f)],'FontName','Times','FontSize',app.police);
            axis (app.UIAxesPhase,[min(app.f) 1e009, min_H_arg max_H_arg]);
            % Reset the lamp status
            app.StatusLamp.Color = [0.9412 0.9412 0.9412]; % The lamp is grayed out
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FILTERDESIGNANDVIEWERLabel = uilabel(app.UIFigure);
            app.FILTERDESIGNANDVIEWERLabel.Text = 'FILTER DESIGN AND VIEWER';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_inputs = uitab(app.TabGroup);
            app.Tab_inputs.Title = 'Inputs';
            app.FiltersparametersLabel = uilabel(app.Tab_inputs);
            app.FiltersparametersLabel.Text = 'Filter''s parameters';
            app.TypeDropDownLabel = uilabel(app.Tab_inputs);
            app.TypeDropDownLabel.Text = 'Type:';
            app.TypeDropDown = uidropdown(app.Tab_inputs);
            app.TypeDropDown.Items = {'Lowpass', 'Highpass', 'Bandpass', 'Stopband'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeValueChanged, true);
            app.TypeDropDown.Value = 'Lowpass';
            app.CutoffFrequencyLabel = uilabel(app.Tab_inputs);
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency:';
            app.CutoffFrequencyValue = uieditfield(app.Tab_inputs, 'numeric');
            app.CutoffFrequencyValue.Limits = [1 1000000000];
            app.CutoffFrequencyValue.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencyValueChanged, true);
            app.CutoffFrequencyValue.Value = 1;
            app.QualityFactorLabel = uilabel(app.Tab_inputs);
            app.QualityFactorLabel.Text = 'Quality Factor:';
            app.QualityFactorValue = uieditfield(app.Tab_inputs, 'numeric');
            app.QualityFactorValue.Limits = [0.1 10];
            app.QualityFactorValue.ValueChangedFcn = createCallbackFcn(app, @QualityFactorValueChanged, true);
            app.QualityFactorValue.Value = 1;
            app.LinearGainLabel = uilabel(app.Tab_inputs);
            app.LinearGainLabel.Text = 'Linear Gain:';
            app.LinearGainValue = uieditfield(app.Tab_inputs, 'numeric');
            app.LinearGainValue.Limits = [0.001 1000];
            app.LinearGainValue.ValueChangedFcn = createCallbackFcn(app, @LinearGainValueChanged, true);
            app.LinearGainValue.Value = 1;
            app.ComputeFiltersDataLabel = uilabel(app.Tab_inputs);
            app.ComputeFiltersDataLabel.Text = 'Compute Filter''s Data';
            app.CreateBodesButton = uibutton(app.Tab_inputs, 'push');
            app.CreateBodesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateBodesButtonPushed, true);
            app.CreateBodesButton.Text = 'Create Bodes';
            app.LogMessageTextAreaLabel = uilabel(app.Tab_inputs);
            app.LogMessageTextAreaLabel.Text = 'Log Message:';
            app.LogMessage = uitextarea(app.Tab_inputs);
            app.StatusLampLabel = uilabel(app.Tab_inputs);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.Tab_inputs);
            app.ViewBodesButton = uibutton(app.Tab_inputs, 'push');
            app.ViewBodesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewBodesButtonPushed, true);
            app.ViewBodesButton.Enable = 'off';
            app.ViewBodesButton.Text = 'View Bodes';
            app.OrderLabel = uilabel(app.Tab_inputs);
            app.OrderLabel.Text = 'Order:';
            app.OrderKnob = uiknob(app.Tab_inputs, 'discrete');
            app.OrderKnob.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.OrderKnob.ValueChangedFcn = createCallbackFcn(app, @OrderValueChanged, true);
            app.OrderKnob.Value = '1';
            app.Tab_outputs = uitab(app.TabGroup);
            app.Tab_outputs.Title = 'Outputs';
            app.UIAxesModule = uiaxes(app.Tab_outputs);
            app.UIAxesModule.XLim = [1 1000000000];
            app.UIAxesModule.YLim = [-100 100];
            app.UIAxesModule.YTick = [-100 -80 -60 -40 -20 0 20 40 60 80 100];
            app.UIAxesModule.YTickLabel = {'-100'; '-80'; '-60'; '-40'; '-20'; '0'; '20'; '40'; '60'; '80'; '100'};
            app.UIAxesModule.XScale = 'log';
            app.UIAxesPhase = uiaxes(app.Tab_outputs);
            app.UIAxesPhase.XLim = [1 1000000000];
            app.UIAxesPhase.YLim = [-180 180];
            app.UIAxesPhase.XTick = [1 10 100 1000 10000 100000 1000000 10000000 100000000];
            app.UIAxesPhase.XTickLabel = {'10^{0}'; '10^{1}'; '10^{2}'; '10^{3}'; '10^{4}'; '10^{5}'; '10^{6}'; '10^{7}'; '10^{8}'; '10^{9}'};
            app.UIAxesPhase.YTick = [-180 -170 -160 -150 -140 -130 -120 -110 -100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.UIAxesPhase.XScale = 'log';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Filter_Design

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end